classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes_RG            matlab.ui.control.UIAxes
        UIAxes_GG            matlab.ui.control.UIAxes
        UIAxes_BG            matlab.ui.control.UIAxes
        UIAxes_RC            matlab.ui.control.UIAxes
        UIAxes_GC            matlab.ui.control.UIAxes
        UIAxes_BC            matlab.ui.control.UIAxes
        UIAxes_RH            matlab.ui.control.UIAxes
        Slider_RL            matlab.ui.control.Slider
        Slider_GL            matlab.ui.control.Slider
        Slider_BL            matlab.ui.control.Slider
        UIAxes_GH            matlab.ui.control.UIAxes
        UIAxes_BH            matlab.ui.control.UIAxes
        RedChannelLabel      matlab.ui.control.Label
        GreenChannelLabel    matlab.ui.control.Label
        BlueChannelLabel     matlab.ui.control.Label
        CloudsRGBLabel       matlab.ui.control.Label
        GroundRGBLabel       matlab.ui.control.Label
        GroundLabel_2        matlab.ui.control.Label
        CloudsLabel_2        matlab.ui.control.Label
        ThresholdRedLabel    matlab.ui.control.Label
        ThresholdGreenLabel  matlab.ui.control.Label
        ThresholdBlueLabel   matlab.ui.control.Label
        GoButton             matlab.ui.control.Button
        UIAxes_RGBC          matlab.ui.control.UIAxes
        UIAxes_RGBG          matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            global redchan grechan bluchan lmachan rbackvalue gbackvalue bbackvalue
            
            rbackvalue = 1;
            gbackvalue = 1;
            bbackvalue = 1;
            
            [redfil,redpat] = uigetfile('*.jpg','Choose a JPEG file for the red channel');
            [grefil,grepat] = uigetfile('*.jpg','Choose a JPEG file for the green channel');
            [blufil,blupat] = uigetfile('*.jpg','Choose a JPEG file for the blue channel');
            %[lmafil,lmapat] = uigetfile('*','Choose a file for the land mask');
            redname = fullfile(redpat,redfil);
            redchan = imread(redname);
            grename = fullfile(grepat,grefil);
            grechan = imread(grename);
            bluname = fullfile(blupat,blufil);
            bluchan = imread(bluname);
            %lmaname = fullfile(lmapat,lmafil);
            %lmachan = imread(lmaname);
            
            hold(app.UIAxes_RH)
            histogram(redchan,'Parent',app.UIAxes_RH);
            imshow(redchan, 'Parent', app.UIAxes_RC);
            imshow(redchan, 'Parent', app.UIAxes_RG);
            %set(app.UIAxes_RH, 'XTickMode', 'manual', 'YTickMode', 'manual')
            hold(app.UIAxes_GH)
            histogram(grechan,'Parent',app.UIAxes_GH);
            imshow(grechan, 'Parent', app.UIAxes_GC);
            imshow(grechan, 'Parent', app.UIAxes_GG);
            %set(app.UIAxes_GH, 'XTickMode', 'manual', 'YTickMode', 'manual')
            hold(app.UIAxes_BH)
            histogram(bluchan,'Parent',app.UIAxes_BH);
            imshow(bluchan, 'Parent', app.UIAxes_BC);
            imshow(bluchan, 'Parent', app.UIAxes_BG);
            %set(app.UIAxes_BH, 'XTickMode', 'manual', 'YTickMode', 'manual')
            
        end

        % Value changed function: Slider_RL
        function Slider_RLValueChanged(app, event)
            global redchan rbackvalue
            xline(rbackvalue,'w-','Parent',app.UIAxes_RH);
            value = app.Slider_RL.Value;
            rbackvalue = value;
            xline(value,'r-','Parent',app.UIAxes_RH);
            thresh_R = app.Slider_RL.Value;
            [respicc_R,respicg_R] = CloudSep(redchan,thresh_R);
            
            imshow(respicc_R, 'Parent', app.UIAxes_RC);
            imshow(respicg_R, 'Parent', app.UIAxes_RG);
        end

        % Value changed function: Slider_GL
        function Slider_GLValueChanged(app, event)
            global grechan gbackvalue
            xline(gbackvalue,'w-','Parent',app.UIAxes_GH);
            value = app.Slider_GL.Value;
            gbackvalue = value;
            xline(value,'g-','Parent',app.UIAxes_GH);
            thresh_G = app.Slider_GL.Value;
            [respicc_G,respicg_G] = CloudSep(grechan,thresh_G);
            imshow(respicc_G, 'Parent', app.UIAxes_GC);
            imshow(respicg_G, 'Parent', app.UIAxes_GG);
        end

        % Value changed function: Slider_BL
        function Slider_BLValueChanged(app, event)
            global bluchan bbackvalue
            xline(bbackvalue,'w-','Parent',app.UIAxes_BH);
            value = app.Slider_BL.Value;
            bbackvalue = value;
            xline(value,'b-','Parent',app.UIAxes_BH);
            thresh_B = app.Slider_BL.Value;
            [respicc_B,respicg_B] = CloudSep(bluchan,thresh_B);
            imshow(respicc_B, 'Parent', app.UIAxes_BC);
            imshow(respicg_B, 'Parent', app.UIAxes_BG);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            global redchan grechan bluchan respicc_R respicg_R respicc_G respicg_G respicc_B respicg_B
        
            %Red
            thresh_R = app.Slider_RL.Value;
            [respicc_R,respicg_R] = CloudSep(redchan,thresh_R);
            imshow(respicc_R, 'Parent', app.UIAxes_RC);
            imshow(respicg_R, 'Parent', app.UIAxes_RG);
            %Green
            thresh_G = app.Slider_GL.Value;
            [respicc_G,respicg_G] = CloudSep(grechan,thresh_G);
            imshow(respicc_G, 'Parent', app.UIAxes_GC);
            imshow(respicg_G, 'Parent', app.UIAxes_GG);
            %Blue
            thresh_B = app.Slider_BL.Value;
            [respicc_B,respicg_B] = CloudSep(bluchan,thresh_B);
            imshow(respicc_B, 'Parent', app.UIAxes_BC);
            imshow(respicg_B, 'Parent', app.UIAxes_BG);
            RGBCImage = RGBcreator(respicc_R,respicc_G,respicc_B);
            imshow(RGBCImage,'Parent',app.UIAxes_RGBC);
            RGBGImage = RGBcreator(respicg_R,respicg_G,respicg_B);
            imshow(RGBGImage,'Parent',app.UIAxes_RGBG);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_RG = uiaxes(app.UIFigure);
            title(app.UIAxes_RG, '')
            xlabel(app.UIAxes_RG, '')
            ylabel(app.UIAxes_RG, '')
            app.UIAxes_RG.XTick = [];
            app.UIAxes_RG.YTick = [];
            app.UIAxes_GG = uiaxes(app.UIFigure);
            title(app.UIAxes_GG, '')
            xlabel(app.UIAxes_GG, '')
            ylabel(app.UIAxes_GG, '')
            app.UIAxes_GG.XTick = [];
            app.UIAxes_GG.YTick = [];
            app.UIAxes_BG = uiaxes(app.UIFigure);
            title(app.UIAxes_BG, '')
            xlabel(app.UIAxes_BG, '')
            ylabel(app.UIAxes_BG, '')
            app.UIAxes_BG.XTick = [];
            app.UIAxes_BG.YTick = [];
            app.UIAxes_RC = uiaxes(app.UIFigure);
            title(app.UIAxes_RC, '')
            xlabel(app.UIAxes_RC, '')
            ylabel(app.UIAxes_RC, '')
            app.UIAxes_RC.XTick = [];
            app.UIAxes_RC.YTick = [];
            app.UIAxes_GC = uiaxes(app.UIFigure);
            title(app.UIAxes_GC, '')
            xlabel(app.UIAxes_GC, '')
            ylabel(app.UIAxes_GC, '')
            app.UIAxes_GC.XTick = [];
            app.UIAxes_GC.YTick = [];
            app.UIAxes_BC = uiaxes(app.UIFigure);
            title(app.UIAxes_BC, '')
            xlabel(app.UIAxes_BC, '')
            ylabel(app.UIAxes_BC, '')
            app.UIAxes_BC.XTick = [];
            app.UIAxes_BC.YTick = [];
            app.UIAxes_RH = uiaxes(app.UIFigure);
            title(app.UIAxes_RH, 'Histogram')
            xlabel(app.UIAxes_RH, 'Brightness')
            ylabel(app.UIAxes_RH, 'Number of Pixels')
            app.Slider_RL = uislider(app.UIFigure);
            app.Slider_RL.Limits = [0 200];
            app.Slider_RL.ValueChangedFcn = createCallbackFcn(app, @Slider_RLValueChanged, true);
            app.Slider_GL = uislider(app.UIFigure);
            app.Slider_GL.Limits = [0 200];
            app.Slider_GL.ValueChangedFcn = createCallbackFcn(app, @Slider_GLValueChanged, true);
            app.Slider_BL = uislider(app.UIFigure);
            app.Slider_BL.Limits = [0 200];
            app.Slider_BL.ValueChangedFcn = createCallbackFcn(app, @Slider_BLValueChanged, true);
            app.UIAxes_GH = uiaxes(app.UIFigure);
            title(app.UIAxes_GH, 'Histogram')
            xlabel(app.UIAxes_GH, 'Brightness')
            ylabel(app.UIAxes_GH, 'Number of Pixels')
            app.UIAxes_BH = uiaxes(app.UIFigure);
            title(app.UIAxes_BH, 'Histogram')
            xlabel(app.UIAxes_BH, 'Brightness')
            ylabel(app.UIAxes_BH, 'Number of Pixels')
            app.RedChannelLabel = uilabel(app.UIFigure);
            app.RedChannelLabel.Text = 'Red Channel';
            app.GreenChannelLabel = uilabel(app.UIFigure);
            app.GreenChannelLabel.Text = 'Green Channel';
            app.BlueChannelLabel = uilabel(app.UIFigure);
            app.BlueChannelLabel.Text = 'Blue Channel';
            app.CloudsRGBLabel = uilabel(app.UIFigure);
            app.CloudsRGBLabel.Text = 'Clouds (RGB)';
            app.GroundRGBLabel = uilabel(app.UIFigure);
            app.GroundRGBLabel.Text = 'Ground (RGB)';
            app.GroundLabel_2 = uilabel(app.UIFigure);
            app.GroundLabel_2.Text = 'Ground';
            app.CloudsLabel_2 = uilabel(app.UIFigure);
            app.CloudsLabel_2.Text = 'Clouds';
            app.ThresholdRedLabel = uilabel(app.UIFigure);
            app.ThresholdRedLabel.Text = 'Threshold (Red)';
            app.ThresholdGreenLabel = uilabel(app.UIFigure);
            app.ThresholdGreenLabel.Text = 'Threshold (Green)';
            app.ThresholdBlueLabel = uilabel(app.UIFigure);
            app.ThresholdBlueLabel.Text = 'Threshold (Blue)';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.UIAxes_RGBC = uiaxes(app.UIFigure);
            title(app.UIAxes_RGBC, '')
            xlabel(app.UIAxes_RGBC, '')
            ylabel(app.UIAxes_RGBC, '')
            app.UIAxes_RGBC.XTick = [];
            app.UIAxes_RGBC.YTick = [];
            app.UIAxes_RGBG = uiaxes(app.UIFigure);
            title(app.UIAxes_RGBG, '')
            xlabel(app.UIAxes_RGBG, '')
            ylabel(app.UIAxes_RGBG, '')
            app.UIAxes_RGBG.XTick = [];
            app.UIAxes_RGBG.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end